classdef EinzelLensGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ElectroStaticLensSimulatorLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        DeviceParametersPanel           matlab.ui.container.Panel
        LensRadiusLabel                 matlab.ui.control.Label
        DeviceRadius                    matlab.ui.control.NumericEditField
        umLabel_3                       matlab.ui.control.Label
        umLabel_6                       matlab.ui.control.Label
        umLabel_7                       matlab.ui.control.Label
        umLabel_5                       matlab.ui.control.Label
        umLabel_4                       matlab.ui.control.Label
        umLabel                         matlab.ui.control.Label
        LeftElectrodeRadiusLabel        matlab.ui.control.Label
        OuterApertureradiuse            matlab.ui.control.NumericEditField
        RightElectrodeRadiusLabel       matlab.ui.control.Label
        InnerApertureradius             matlab.ui.control.NumericEditField
        DistanceBetweenElectrodesLabel  matlab.ui.control.Label
        DistanceBetweenSurfaces         matlab.ui.control.NumericEditField
        LensPreOffsetLabel              matlab.ui.control.Label
        SideOffset                      matlab.ui.control.NumericEditField
        ElectrodesWidthEditFieldLabel   matlab.ui.control.Label
        ElectrodesWidth                 matlab.ui.control.NumericEditField
        NumberOfUnitCellsEditFieldLabel  matlab.ui.control.Label
        NumberOfUnitCells               matlab.ui.control.NumericEditField
        VoltsLabel                      matlab.ui.control.Label
        VoltsLabel_2                    matlab.ui.control.Label
        LeftElectrodeVoltageLabel       matlab.ui.control.Label
        V1                              matlab.ui.control.NumericEditField
        RightElectrodeVoltageLabel      matlab.ui.control.Label
        V2                              matlab.ui.control.NumericEditField
        LensPostOffsetEditFieldLabel    matlab.ui.control.Label
        LensPostOffsetEditField         matlab.ui.control.NumericEditField
        umLabel_11                      matlab.ui.control.Label
        ResolutionParametersPanel       matlab.ui.container.Panel
        OptimizeResolution              matlab.ui.control.CheckBox
        Perinstance                     matlab.ui.control.Label
        NumberofBoundariesLabel         matlab.ui.control.Label
        NumberofBoundaries              matlab.ui.control.NumericEditField
        NumberOfChargesLabel            matlab.ui.control.Label
        NumberOfCharges                 matlab.ui.control.NumericEditField
        RadialDirectionGridPointsEditFieldLabel  matlab.ui.control.Label
        RadialDirectionGridPoints       matlab.ui.control.NumericEditField
        AxialDirectionGridPointsEditFieldLabel  matlab.ui.control.Label
        AxialDirectionGridPoints        matlab.ui.control.NumericEditField
        ConvergenceThresholdEditFieldLabel  matlab.ui.control.Label
        ConvergenceThreshold            matlab.ui.control.NumericEditField
        GrowthThresholdEditFieldLabel   matlab.ui.control.Label
        GrowthThreshold                 matlab.ui.control.NumericEditField
        MultiParticlesPanel             matlab.ui.container.Panel
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        umLabel_8                       matlab.ui.control.Label
        EntryBeamRadiusEditFieldLabel   matlab.ui.control.Label
        EntryBeamRadiusEditField        matlab.ui.control.NumericEditField
        ParticlesKineticEnergyEditFieldLabel  matlab.ui.control.Label
        ParticlesKineticEnergyEditField  matlab.ui.control.NumericEditField
        keVLabel                        matlab.ui.control.Label
        MaxInitialMomentEditFieldLabel  matlab.ui.control.Label
        MaxInitialMomentEditField       matlab.ui.control.NumericEditField
        WhichTrajectorytoSimulateButtonGroup  matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        SimulateSingleParticleButton    matlab.ui.control.RadioButton
        SimulateMultiParticleButton     matlab.ui.control.RadioButton
        SingleParticlePanel             matlab.ui.container.Panel
        umLabel_9                       matlab.ui.control.Label
        AxialEntrySpeedEditFieldLabel   matlab.ui.control.Label
        AxialEntrySpeed                 matlab.ui.control.NumericEditField
        RadialEntrySpeedEditFieldLabel  matlab.ui.control.Label
        RadialEntrySpeed                matlab.ui.control.NumericEditField
        EntryRadiusLabel                matlab.ui.control.Label
        EntryRPosition                  matlab.ui.control.NumericEditField
        cLabel_2                        matlab.ui.control.Label
        cLabel_3                        matlab.ui.control.Label
        ExitRadiusThresholdEditFieldLabel  matlab.ui.control.Label
        ExitRadiusThresholdEditField    matlab.ui.control.NumericEditField
        umLabel_10                      matlab.ui.control.Label
        FiguresPanel                    matlab.ui.container.Panel
        FullSimCheckBox                 matlab.ui.control.CheckBox
        PhaseSpaceCheckBox              matlab.ui.control.CheckBox
        PhaseSpaceVideoCheckBox         matlab.ui.control.CheckBox
        ChargeDistributionCheckBox      matlab.ui.control.CheckBox
        ProblematicParticlesCheckBox    matlab.ui.control.CheckBox
        EmittancevsZCheckBox            matlab.ui.control.CheckBox
        ParticlesParametersPanel        matlab.ui.container.Panel
        umLabel_2                       matlab.ui.control.Label
        ElectronElectrodeminimumdistanceEditFieldLabel  matlab.ui.control.Label
        ElectronElectrodeminimumdistanceEditField  matlab.ui.control.NumericEditField
        eLabel                          matlab.ui.control.Label
        MeLabel                         matlab.ui.control.Label
        ParticleChargeEditFieldLabel_2  matlab.ui.control.Label
        ParticleCharge                  matlab.ui.control.NumericEditField
        ParticleMassEditFieldLabel_2    matlab.ui.control.Label
        ParticleMass                    matlab.ui.control.NumericEditField
        SimulateElectrons               matlab.ui.control.CheckBox
        SaveSimulationPanel             matlab.ui.container.Panel
        EraseOldSim                     matlab.ui.control.CheckBox
        SimulationNameEditFieldLabel    matlab.ui.control.Label
        SimulationName                  matlab.ui.control.EditField
        newRandSeed                     matlab.ui.control.CheckBox
        ProjectNameLabel                matlab.ui.control.Label
        ProjectNameEditField            matlab.ui.control.EditField
        SaveFigurestoDiskCheckBox       matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateCallBack(app, event)
            c0 = 3e8;   
            
            %device Parameters
            params.deviceRadius              = app.DeviceRadius.Value/1e6;
            params.lensPreOffset             = app.SideOffset.Value/(1e6);
            params.lensPostOffset            = app.LensPostOffsetEditField.Value/(1e6);
            params.distanceBetweenElectrodes = app.DistanceBetweenSurfaces.Value/1e6;
            params.leftElectrodeRadius       = app.OuterApertureradiuse.Value/1e6;
            params.rightElectrodeRadius      = app.InnerApertureradius.Value/1e6;
            params.electrodeWidth            = app.ElectrodesWidth.Value/1e6;
            params.repetitions               = app.NumberOfUnitCells.Value;
            params.VaLeft                    = app.V1.Value;
            params.VaRight                   = app.V2.Value;
            
            %Resolution Parameters     
            params.M                         = app.NumberofBoundaries.Value;
            params.N                         = app.NumberOfCharges.Value;     
            params.rPts                      = app.RadialDirectionGridPoints.Value;
            params.zPts                      = app.AxialDirectionGridPoints.Value;
            params.dimensionsOptimization    = app.OptimizeResolution.Value;
            params.convergeTh                = app.ConvergenceThreshold.Value;
            params.growthTh                  = app.GrowthThreshold.Value;
            
            %what To Simulate
            params.dontSimulateTrajectory  =  app.NoneButton.Value;
            params.simulateSingleParticle  =  app.SimulateSingleParticleButton.Value;
            params.simulateMultiParticles  =  app.SimulateMultiParticleButton.Value;
            
            %Figures
            params.plotFullSim              = app.FullSimCheckBox.Value;
            params.plotPhaseSpace           = app.PhaseSpaceCheckBox.Value;
            params.plotPhaseSpaceVideo      = app.PhaseSpaceVideoCheckBox.Value;
            params.plotChargeDistribution   = app.ChargeDistributionCheckBox.Value;
            params.plotProblematicParticles = app.ProblematicParticlesCheckBox.Value;
            params.plotEmittanceVsZ         = app.EmittancevsZCheckBox.Value;
            
            %Particle Parameters
            params.electrodeProximityThresh  = app.ElectronElectrodeminimumdistanceEditField.Value/1e6;
            params.q                         = app.ParticleCharge.Value;
            params.m                         = app.ParticleMass.Value;
            params.simulateElectron          = app.SimulateElectrons.Value;
            
            %Multi Particle
            params.numOfParticles    = app.NumberofParticlesEditField.Value;
            params.beamInitialRadius = app.EntryBeamRadiusEditField.Value/1e6;
            params.maxInitMoment     = app.MaxInitialMomentEditField.Value;
            params.Ek                = app.ParticlesKineticEnergyEditField.Value*1e3;
            
            %Single Particle
            params.axialEntryVelocity  = app.AxialEntrySpeed.Value*c0;
            params.radialEntryVelocity = app.RadialEntrySpeed.Value*c0;
            params.entryR              = app.EntryRPosition.Value/1e6;
            params.exitRthresh         = app.ExitRadiusThresholdEditField.Value/1e6;
             
            %Save Sim
            params.simName       = app.SimulationName.Value;
            params.simGlobalName = app.ProjectNameEditField.Value;
            params.eraseOldSim   = app.EraseOldSim.Value;
            params.genNewSeed    = app.newRandSeed.Value;
            params.savePlots     = app.SaveFigurestoDiskCheckBox.Value;
            params.simPdName     = params.simName;
            params.SingleSim     = true;
            runSim(params);
                     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ElectroStaticLensSimulatorLabel = uilabel(app.UIFigure);
            app.ElectroStaticLensSimulatorLabel.Text = 'ElectroStatic Lens Simulator';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateCallBack, true);
            app.SimulateButton.Text = 'Simulate!';
            app.DeviceParametersPanel = uipanel(app.UIFigure);
            app.DeviceParametersPanel.Title = 'Device Parameters';
            app.LensRadiusLabel = uilabel(app.DeviceParametersPanel);
            app.LensRadiusLabel.Text = 'Lens Radius';
            app.DeviceRadius = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.DeviceRadius.Limits = [0 Inf];
            app.DeviceRadius.Value = 5;
            app.umLabel_3 = uilabel(app.DeviceParametersPanel);
            app.umLabel_3.Text = '[um]';
            app.umLabel_6 = uilabel(app.DeviceParametersPanel);
            app.umLabel_6.Text = '[um]';
            app.umLabel_7 = uilabel(app.DeviceParametersPanel);
            app.umLabel_7.Text = '[um]';
            app.umLabel_5 = uilabel(app.DeviceParametersPanel);
            app.umLabel_5.Text = '[um]';
            app.umLabel_4 = uilabel(app.DeviceParametersPanel);
            app.umLabel_4.Text = '[um]';
            app.umLabel = uilabel(app.DeviceParametersPanel);
            app.umLabel.Text = '[um]';
            app.LeftElectrodeRadiusLabel = uilabel(app.DeviceParametersPanel);
            app.LeftElectrodeRadiusLabel.Text = 'Left Electrode Radius';
            app.OuterApertureradiuse = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.OuterApertureradiuse.Value = 1;
            app.RightElectrodeRadiusLabel = uilabel(app.DeviceParametersPanel);
            app.RightElectrodeRadiusLabel.Text = 'Right Electrode Radius';
            app.InnerApertureradius = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.InnerApertureradius.Value = 1;
            app.DistanceBetweenElectrodesLabel = uilabel(app.DeviceParametersPanel);
            app.DistanceBetweenElectrodesLabel.Text = 'Distance Between Electrodes:';
            app.DistanceBetweenSurfaces = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.DistanceBetweenSurfaces.Value = 1;
            app.LensPreOffsetLabel = uilabel(app.DeviceParametersPanel);
            app.LensPreOffsetLabel.Text = 'Lens Pre Offset';
            app.SideOffset = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.SideOffset.Value = 2;
            app.ElectrodesWidthEditFieldLabel = uilabel(app.DeviceParametersPanel);
            app.ElectrodesWidthEditFieldLabel.Text = 'Electrodes Width';
            app.ElectrodesWidth = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.ElectrodesWidth.Value = 0.01;
            app.NumberOfUnitCellsEditFieldLabel = uilabel(app.DeviceParametersPanel);
            app.NumberOfUnitCellsEditFieldLabel.Text = 'Number Of Unit Cells';
            app.NumberOfUnitCells = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.NumberOfUnitCells.Limits = [1 Inf];
            app.NumberOfUnitCells.Value = 1;
            app.VoltsLabel = uilabel(app.DeviceParametersPanel);
            app.VoltsLabel.Text = 'Volts';
            app.VoltsLabel_2 = uilabel(app.DeviceParametersPanel);
            app.VoltsLabel_2.Text = 'Volts';
            app.LeftElectrodeVoltageLabel = uilabel(app.DeviceParametersPanel);
            app.LeftElectrodeVoltageLabel.Text = 'Left Electrode Voltage';
            app.V1 = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.V1.ValueDisplayFormat = '%11.6g';
            app.V1.Value = -15;
            app.RightElectrodeVoltageLabel = uilabel(app.DeviceParametersPanel);
            app.RightElectrodeVoltageLabel.Text = 'Right Electrode Voltage';
            app.V2 = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.V2.ValueDisplayFormat = '%11.6g';
            app.V2.Value = 15;
            app.LensPostOffsetEditFieldLabel = uilabel(app.DeviceParametersPanel);
            app.LensPostOffsetEditFieldLabel.Text = 'Lens Post Offset';
            app.LensPostOffsetEditField = uieditfield(app.DeviceParametersPanel, 'numeric');
            app.LensPostOffsetEditField.Value = 2;
            app.umLabel_11 = uilabel(app.DeviceParametersPanel);
            app.umLabel_11.Text = '[um]';
            app.ResolutionParametersPanel = uipanel(app.UIFigure);
            app.ResolutionParametersPanel.Title = 'Resolution Parameters';
            app.OptimizeResolution = uicheckbox(app.ResolutionParametersPanel);
            app.OptimizeResolution.Text = 'Optimize Resolution';
            app.Perinstance = uilabel(app.ResolutionParametersPanel);
            app.Perinstance.Text = '*Per instance';
            app.NumberofBoundariesLabel = uilabel(app.ResolutionParametersPanel);
            app.NumberofBoundariesLabel.Text = 'Number of Boundaries*';
            app.NumberofBoundaries = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.NumberofBoundaries.Value = 100;
            app.NumberOfChargesLabel = uilabel(app.ResolutionParametersPanel);
            app.NumberOfChargesLabel.Text = 'Number Of Charges*';
            app.NumberOfCharges = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.NumberOfCharges.Value = 25;
            app.RadialDirectionGridPointsEditFieldLabel = uilabel(app.ResolutionParametersPanel);
            app.RadialDirectionGridPointsEditFieldLabel.Text = 'Radial Direction Grid Points';
            app.RadialDirectionGridPoints = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.RadialDirectionGridPoints.Value = 200;
            app.AxialDirectionGridPointsEditFieldLabel = uilabel(app.ResolutionParametersPanel);
            app.AxialDirectionGridPointsEditFieldLabel.Text = 'Axial Direction Grid Points';
            app.AxialDirectionGridPoints = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.AxialDirectionGridPoints.Value = 200;
            app.ConvergenceThresholdEditFieldLabel = uilabel(app.ResolutionParametersPanel);
            app.ConvergenceThresholdEditFieldLabel.Text = 'Convergence Threshold';
            app.ConvergenceThreshold = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.ConvergenceThreshold.Value = 5e-08;
            app.GrowthThresholdEditFieldLabel = uilabel(app.ResolutionParametersPanel);
            app.GrowthThresholdEditFieldLabel.Text = 'Growth Threshold';
            app.GrowthThreshold = uieditfield(app.ResolutionParametersPanel, 'numeric');
            app.GrowthThreshold.Value = 5e-05;
            app.MultiParticlesPanel = uipanel(app.UIFigure);
            app.MultiParticlesPanel.Title = 'Multi Particles';
            app.NumberofParticlesEditFieldLabel = uilabel(app.MultiParticlesPanel);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.MultiParticlesPanel, 'numeric');
            app.NumberofParticlesEditField.Value = 50;
            app.umLabel_8 = uilabel(app.MultiParticlesPanel);
            app.umLabel_8.Text = '[um]';
            app.EntryBeamRadiusEditFieldLabel = uilabel(app.MultiParticlesPanel);
            app.EntryBeamRadiusEditFieldLabel.Text = 'Entry Beam Radius';
            app.EntryBeamRadiusEditField = uieditfield(app.MultiParticlesPanel, 'numeric');
            app.EntryBeamRadiusEditField.Value = 0.5;
            app.ParticlesKineticEnergyEditFieldLabel = uilabel(app.MultiParticlesPanel);
            app.ParticlesKineticEnergyEditFieldLabel.Text = 'Particles Kinetic Energy';
            app.ParticlesKineticEnergyEditField = uieditfield(app.MultiParticlesPanel, 'numeric');
            app.ParticlesKineticEnergyEditField.Value = 10;
            app.keVLabel = uilabel(app.MultiParticlesPanel);
            app.keVLabel.Text = '[keV]';
            app.MaxInitialMomentEditFieldLabel = uilabel(app.MultiParticlesPanel);
            app.MaxInitialMomentEditFieldLabel.Text = 'Max Initial Moment';
            app.MaxInitialMomentEditField = uieditfield(app.MultiParticlesPanel, 'numeric');
            app.MaxInitialMomentEditField.Value = 0.001;
            app.WhichTrajectorytoSimulateButtonGroup = uibuttongroup(app.UIFigure);
            app.WhichTrajectorytoSimulateButtonGroup.Title = 'Which Trajectory to Simulate? ';
            app.NoneButton = uiradiobutton(app.WhichTrajectorytoSimulateButtonGroup);
            app.NoneButton.Text = 'None';
            app.SimulateSingleParticleButton = uiradiobutton(app.WhichTrajectorytoSimulateButtonGroup);
            app.SimulateSingleParticleButton.Text = 'Simulate Single Particle';
            app.SimulateSingleParticleButton.Value = true;
            app.SimulateMultiParticleButton = uiradiobutton(app.WhichTrajectorytoSimulateButtonGroup);
            app.SimulateMultiParticleButton.Text = 'Simulate Multi Particle';
            app.SingleParticlePanel = uipanel(app.UIFigure);
            app.SingleParticlePanel.Title = 'Single Particle';
            app.umLabel_9 = uilabel(app.SingleParticlePanel);
            app.umLabel_9.Text = '[um]';
            app.AxialEntrySpeedEditFieldLabel = uilabel(app.SingleParticlePanel);
            app.AxialEntrySpeedEditFieldLabel.Text = 'Axial Entry Speed';
            app.AxialEntrySpeed = uieditfield(app.SingleParticlePanel, 'numeric');
            app.AxialEntrySpeed.Value = 0.1;
            app.RadialEntrySpeedEditFieldLabel = uilabel(app.SingleParticlePanel);
            app.RadialEntrySpeedEditFieldLabel.Text = 'Radial Entry Speed';
            app.RadialEntrySpeed = uieditfield(app.SingleParticlePanel, 'numeric');
            app.RadialEntrySpeed.Value = 0.001;
            app.EntryRadiusLabel = uilabel(app.SingleParticlePanel);
            app.EntryRadiusLabel.Text = 'Entry Radius ';
            app.EntryRPosition = uieditfield(app.SingleParticlePanel, 'numeric');
            app.cLabel_2 = uilabel(app.SingleParticlePanel);
            app.cLabel_2.Text = '[c]';
            app.cLabel_3 = uilabel(app.SingleParticlePanel);
            app.cLabel_3.Text = '[c]';
            app.ExitRadiusThresholdEditFieldLabel = uilabel(app.SingleParticlePanel);
            app.ExitRadiusThresholdEditFieldLabel.Text = 'Exit Radius Threshold';
            app.ExitRadiusThresholdEditField = uieditfield(app.SingleParticlePanel, 'numeric');
            app.ExitRadiusThresholdEditField.Value = 0.5;
            app.umLabel_10 = uilabel(app.SingleParticlePanel);
            app.umLabel_10.Text = '[um]';
            app.FiguresPanel = uipanel(app.UIFigure);
            app.FiguresPanel.Title = 'Figures';
            app.FullSimCheckBox = uicheckbox(app.FiguresPanel);
            app.FullSimCheckBox.Text = 'Full Sim';
            app.PhaseSpaceCheckBox = uicheckbox(app.FiguresPanel);
            app.PhaseSpaceCheckBox.Text = 'Phase Space';
            app.PhaseSpaceVideoCheckBox = uicheckbox(app.FiguresPanel);
            app.PhaseSpaceVideoCheckBox.Text = 'Phase Space Video';
            app.ChargeDistributionCheckBox = uicheckbox(app.FiguresPanel);
            app.ChargeDistributionCheckBox.Text = 'Charge Distribution';
            app.ProblematicParticlesCheckBox = uicheckbox(app.FiguresPanel);
            app.ProblematicParticlesCheckBox.Text = 'Problematic Particles';
            app.EmittancevsZCheckBox = uicheckbox(app.FiguresPanel);
            app.EmittancevsZCheckBox.Text = 'Emittance vs. Z';
            app.ParticlesParametersPanel = uipanel(app.UIFigure);
            app.ParticlesParametersPanel.Title = 'Particles Parameters';
            app.umLabel_2 = uilabel(app.ParticlesParametersPanel);
            app.umLabel_2.Text = '[um]';
            app.ElectronElectrodeminimumdistanceEditFieldLabel = uilabel(app.ParticlesParametersPanel);
            app.ElectronElectrodeminimumdistanceEditFieldLabel.Text = 'Electron-Electrode minimum distance';
            app.ElectronElectrodeminimumdistanceEditField = uieditfield(app.ParticlesParametersPanel, 'numeric');
            app.ElectronElectrodeminimumdistanceEditField.Value = 0.001;
            app.eLabel = uilabel(app.ParticlesParametersPanel);
            app.eLabel.Text = '[e]';
            app.MeLabel = uilabel(app.ParticlesParametersPanel);
            app.MeLabel.Text = '[Me]';
            app.ParticleChargeEditFieldLabel_2 = uilabel(app.ParticlesParametersPanel);
            app.ParticleChargeEditFieldLabel_2.Text = 'Particle Charge';
            app.ParticleCharge = uieditfield(app.ParticlesParametersPanel, 'numeric');
            app.ParticleCharge.Value = 1;
            app.ParticleMassEditFieldLabel_2 = uilabel(app.ParticlesParametersPanel);
            app.ParticleMassEditFieldLabel_2.Text = 'Particle Mass';
            app.ParticleMass = uieditfield(app.ParticlesParametersPanel, 'numeric');
            app.ParticleMass.Value = 1;
            app.SimulateElectrons = uicheckbox(app.ParticlesParametersPanel);
            app.SimulateElectrons.Text = 'Simulate Electrons';
            app.SimulateElectrons.Value = true;
            app.SaveSimulationPanel = uipanel(app.UIFigure);
            app.SaveSimulationPanel.Title = 'Save Simulation';
            app.EraseOldSim = uicheckbox(app.SaveSimulationPanel);
            app.EraseOldSim.Text = {'Overwrite old simulation contents'; '(Useful if a previous simulation with the same name already exists on the disk'; 'NOTE: If this box is unchecked and a simulation with the same name already'; 'exists, the device parameters that are set will be ignored)'};
            app.EraseOldSim.Value = true;
            app.SimulationNameEditFieldLabel = uilabel(app.SaveSimulationPanel);
            app.SimulationNameEditFieldLabel.Text = 'Simulation Name:';
            app.SimulationName = uieditfield(app.SaveSimulationPanel, 'text');
            app.SimulationName.Value = 'temp';
            app.newRandSeed = uicheckbox(app.SaveSimulationPanel);
            app.newRandSeed.Text = 'Generate new random seed (for particle trajectory simulation)';
            app.ProjectNameLabel = uilabel(app.SaveSimulationPanel);
            app.ProjectNameLabel.Text = 'Project Name:';
            app.ProjectNameEditField = uieditfield(app.SaveSimulationPanel, 'text');
            app.ProjectNameEditField.Value = 'ElectroStaticLens';
            app.SaveFigurestoDiskCheckBox = uicheckbox(app.SaveSimulationPanel);
            app.SaveFigurestoDiskCheckBox.Text = 'Save Figures to Disk';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EinzelLensGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end