classdef EinzelLensGui_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VaLeftElectrodeLabel            matlab.ui.control.Label
        V1                              matlab.ui.control.NumericEditField
        VaRightElectrodeLabel           matlab.ui.control.Label
        V2                              matlab.ui.control.NumericEditField
        ElectricalPropertiesLabel       matlab.ui.control.Label
        DeviceParametersLabel           matlab.ui.control.Label
        LeftElectrodeRadiusLabel        matlab.ui.control.Label
        OuterApertureradiuse            matlab.ui.control.NumericEditField
        RightElectrodeRadiusLabel       matlab.ui.control.Label
        InnerApertureradius             matlab.ui.control.NumericEditField
        EinzelLensSimulatorLabel        matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        SimulateMotion                  matlab.ui.control.CheckBox
        VelocityMagnitudeEditFieldLabel  matlab.ui.control.Label
        VelocityMagnitude               matlab.ui.control.NumericEditField
        EntryAngelEditFieldLabel        matlab.ui.control.Label
        EntryAngel                      matlab.ui.control.NumericEditField
        InSingleElectronChargeeLabel    matlab.ui.control.Label
        InSingleElectronMassMeLabel     matlab.ui.control.Label
        kVoltsLabel                     matlab.ui.control.Label
        kVoltsLabel_2                   matlab.ui.control.Label
        LIghtsspeedCoefficientLabel     matlab.ui.control.Label
        DegLabel                        matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        DeviceRadiusEditFieldLabel      matlab.ui.control.Label
        DeviceRadius                    matlab.ui.control.NumericEditField
        mmLabel_3                       matlab.ui.control.Label
        DistanceBetweenElectrodesLabel  matlab.ui.control.Label
        DistanceBetweenSurfaces         matlab.ui.control.NumericEditField
        EntryRadiusLabel                matlab.ui.control.Label
        EntryRPosition                  matlab.ui.control.NumericEditField
        mmLabel_6                       matlab.ui.control.Label
        ElectrodesSideOffsetLabel       matlab.ui.control.Label
        SideOffset                      matlab.ui.control.NumericEditField
        mmLabel_2                       matlab.ui.control.Label
        ResolutionParameters            matlab.ui.control.Label
        OptimizeResolution              matlab.ui.control.CheckBox
        NumberofBoundariesLabel         matlab.ui.control.Label
        NumberofBoundaries              matlab.ui.control.NumericEditField
        NumberOfChargesLabel            matlab.ui.control.Label
        NumberOfCharges                 matlab.ui.control.NumericEditField
        Perinstance                     matlab.ui.control.Label
        SimulateElectrons               matlab.ui.control.CheckBox
        ParticleChargeEditFieldLabel_2  matlab.ui.control.Label
        ParticleCharge                  matlab.ui.control.NumericEditField
        ParticleMassEditFieldLabel_2    matlab.ui.control.Label
        ParticleMass                    matlab.ui.control.NumericEditField
        UseAngle                        matlab.ui.control.CheckBox
        AxialEntrySpeedEditFieldLabel   matlab.ui.control.Label
        AxialEntrySpeed                 matlab.ui.control.NumericEditField
        RadialEntrySpeedEditFieldLabel  matlab.ui.control.Label
        RadialEntrySpeed                matlab.ui.control.NumericEditField
        LIghtsspeedCoefficient          matlab.ui.control.Label
        LIghtsspeedCoefficientLabel_3   matlab.ui.control.Label
        UseModifiedBesselBasedSolution  matlab.ui.control.CheckBox
        ParticleTrajectoryParameters    matlab.ui.control.Label
        SimulationNameEditFieldLabel    matlab.ui.control.Label
        SimulationName                  matlab.ui.control.EditField
        ElectrodesWidthEditFieldLabel   matlab.ui.control.Label
        ElectrodesWidth                 matlab.ui.control.NumericEditField
        umLabel                         matlab.ui.control.Label
        NumberOfUnitCellsEditFieldLabel  matlab.ui.control.Label
        NumberOfUnitCells               matlab.ui.control.NumericEditField
        RadialDirectionGridPointsEditFieldLabel  matlab.ui.control.Label
        RadialDirectionGridPoints       matlab.ui.control.NumericEditField
        AxialDirectionGridPointsEditFieldLabel  matlab.ui.control.Label
        AxialDirectionGridPoints        matlab.ui.control.NumericEditField
        ConvergenceThresholdEditFieldLabel  matlab.ui.control.Label
        ConvergenceThreshold            matlab.ui.control.NumericEditField
        GrowthThresholdEditFieldLabel   matlab.ui.control.Label
        GrowthThreshold                 matlab.ui.control.NumericEditField
        SimulateRelativeTrajectoryCheckBox  matlab.ui.control.CheckBox
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        NumberofParticlesEditField      matlab.ui.control.NumericEditField
        SimulatePhaseSpaceCheckBox      matlab.ui.control.CheckBox
        HighAxialEntryVelocityEditFieldLabel  matlab.ui.control.Label
        HighAxialEntryVelocityEditField  matlab.ui.control.NumericEditField
        LowAxialEntryVelocityEditFieldLabel  matlab.ui.control.Label
        LowAxialEntryVelocityEditField  matlab.ui.control.NumericEditField
        HighRadialEntryVelocityEditFieldLabel  matlab.ui.control.Label
        HighRadialEntryVelocityEditField  matlab.ui.control.NumericEditField
        LowRadialEntryVelocityEditFieldLabel  matlab.ui.control.Label
        LowRadialEntryVelocityEditField  matlab.ui.control.NumericEditField
        ExitRadiusThresholdEditFieldLabel  matlab.ui.control.Label
        ExitRadiusThresholdEditField    matlab.ui.control.NumericEditField
        ElectronElectrodeminimumdistanceEditFieldLabel  matlab.ui.control.Label
        ElectronElectrodeminimumdistanceEditField  matlab.ui.control.NumericEditField
        umLabel_2                       matlab.ui.control.Label
        LIghtsspeedCoefficientLabel_4   matlab.ui.control.Label
        LIghtsspeedCoefficientLabel_5   matlab.ui.control.Label
        LIghtsspeedCoefficientLabel_6   matlab.ui.control.Label
        LIghtsspeedCoefficientLabel_7   matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        EraseOldSim                     matlab.ui.control.CheckBox
        newRandSeed                     matlab.ui.control.CheckBox
        recordPhaseSpaceVideo           matlab.ui.control.CheckBox
        EntryBeamRadiusEditFieldLabel   matlab.ui.control.Label
        EntryBeamRadiusEditField        matlab.ui.control.NumericEditField
        mmLabel_8                       matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateCallBack(app, event)
            c0 = 3e8;
            e0 = -1.60217662e-19;
            eM = 9.10938356e-31;          
            
params.dimensionsOptimization    = app.OptimizeResolution.Value;
params.simulateTrajectory        = app.SimulateMotion.Value;
params.useAngle                  = app.UseAngle.Value;
params.use_bessel                = app.UseModifiedBesselBasedSolution.Value;
params.simulateElectron          = app.SimulateElectrons.Value;
params.BunchOrSingle             = 0;
params.VaLeft                    = app.V1.Value*1e3;
params.VaRight                   = app.V2.Value*1e3;
params.electrodeWidth            = app.ElectrodesWidth.Value/1e6;
params.leftElectrodeRadius       = app.OuterApertureradiuse.Value/1e3;
params.rightElectrodeRadius      = app.InnerApertureradius.Value/1e3;
params.deviceRadius              = app.DeviceRadius.Value/1e3;
params.distanceBetweenElectrodes = app.DistanceBetweenSurfaces.Value/1e3;
params.repetitions               = app.NumberOfUnitCells.Value;
params.sideOffset                = app.SideOffset.Value/(1e3);
params.M                         = app.NumberofBoundaries.Value;
params.N                         = app.NumberOfCharges.Value;     
params.rPts                      = app.RadialDirectionGridPoints.Value;
params.zPts                      = app.AxialDirectionGridPoints.Value;
params.convergeTh                = app.ConvergenceThreshold.Value;
params.growthTh                  = app.GrowthThreshold.Value;
params.entryVel                  = app.VelocityMagnitude.Value*c0;
params.entryAngle                = app.EntryAngel.Value;
params.entryR                    = app.EntryRPosition.Value/1e3;
params.q                         = app.ParticleCharge.Value*e0;
params.m                         = app.ParticleMass.Value*eM;
params.axialEntryVelocity        = app.AxialEntrySpeed.Value*c0;
params.radialEntryVelocity       = app.RadialEntrySpeed.Value*c0;
params.useRelativeTrajectory     = app.SimulateRelativeTrajectoryCheckBox.Value; 
params.electrodeProximityThresh  = app.ElectronElectrodeminimumdistanceEditField.Value/1e6;
params.numOfParticles            = app.NumberofParticlesEditField.Value;
params.lowAxialVel               = app.LowAxialEntryVelocityEditField.Value*c0;
params.highAxialVel              = app.HighAxialEntryVelocityEditField.Value*c0;
params.lowRadialVel              = app.LowRadialEntryVelocityEditField.Value*c0;
params.highRadialVel             = app.HighRadialEntryVelocityEditField.Value*c0;
params.exitRthresh               = app.ExitRadiusThresholdEditField.Value/1e3;
params.simulatePhaseSpace        = app.SimulatePhaseSpaceCheckBox.Value;
params.genNewSeed               = app.newRandSeed.Value;
params.simName                  = app.SimulationName.Value;
params.simGlobalName            =  params.simName;
params.eraseOldSim              = app.EraseOldSim.Value;
params.recordPhaseSpace         = app.recordPhaseSpaceVideo.Value;
params.SingleSim                 = true;
params.beamInitialRadius         =  app.EntryBeamRadiusEditField.Value/1e3;
            
runSim(params);
                     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.VaLeftElectrodeLabel = uilabel(app.UIFigure);
            app.VaLeftElectrodeLabel.Text = 'Va Left Electrode:';
            app.V1 = uieditfield(app.UIFigure, 'numeric');
            app.V1.ValueDisplayFormat = '%11.6g';
            app.V1.Value = -15;
            app.VaRightElectrodeLabel = uilabel(app.UIFigure);
            app.VaRightElectrodeLabel.Text = 'Va Right Electrode:';
            app.V2 = uieditfield(app.UIFigure, 'numeric');
            app.V2.ValueDisplayFormat = '%11.6g';
            app.V2.Value = 15;
            app.ElectricalPropertiesLabel = uilabel(app.UIFigure);
            app.ElectricalPropertiesLabel.Text = 'Electrical Properties:';
            app.DeviceParametersLabel = uilabel(app.UIFigure);
            app.DeviceParametersLabel.Text = 'Device Parameters:';
            app.LeftElectrodeRadiusLabel = uilabel(app.UIFigure);
            app.LeftElectrodeRadiusLabel.Text = 'Left Electrode Radius';
            app.OuterApertureradiuse = uieditfield(app.UIFigure, 'numeric');
            app.OuterApertureradiuse.Value = 1;
            app.RightElectrodeRadiusLabel = uilabel(app.UIFigure);
            app.RightElectrodeRadiusLabel.Text = 'Right Electrode Radius';
            app.InnerApertureradius = uieditfield(app.UIFigure, 'numeric');
            app.InnerApertureradius.Value = 1;
            app.EinzelLensSimulatorLabel = uilabel(app.UIFigure);
            app.EinzelLensSimulatorLabel.Text = 'Einzel Lens Simulator';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateCallBack, true);
            app.SimulateButton.Text = 'Simulate!';
            app.SimulateMotion = uicheckbox(app.UIFigure);
            app.SimulateMotion.Text = 'Simulate Trajectory';
            app.SimulateMotion.Value = true;
            app.VelocityMagnitudeEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityMagnitudeEditFieldLabel.Text = 'Velocity Magnitude';
            app.VelocityMagnitude = uieditfield(app.UIFigure, 'numeric');
            app.VelocityMagnitude.Limits = [0 1];
            app.VelocityMagnitude.Value = 0.1;
            app.EntryAngelEditFieldLabel = uilabel(app.UIFigure);
            app.EntryAngelEditFieldLabel.Text = 'Entry Angel';
            app.EntryAngel = uieditfield(app.UIFigure, 'numeric');
            app.EntryAngel.Limits = [0 90];
            app.EntryAngel.Value = 45;
            app.InSingleElectronChargeeLabel = uilabel(app.UIFigure);
            app.InSingleElectronChargeeLabel.Text = 'In Single Electron Charge e-';
            app.InSingleElectronMassMeLabel = uilabel(app.UIFigure);
            app.InSingleElectronMassMeLabel.Text = 'In Single Electron Mass Me';
            app.kVoltsLabel = uilabel(app.UIFigure);
            app.kVoltsLabel.Text = 'kVolts';
            app.kVoltsLabel_2 = uilabel(app.UIFigure);
            app.kVoltsLabel_2.Text = 'kVolts';
            app.LIghtsspeedCoefficientLabel = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel.Text = 'LIghts'' speed Coefficient';
            app.DegLabel = uilabel(app.UIFigure);
            app.DegLabel.Text = '[Deg]';
            app.mmLabel_4 = uilabel(app.UIFigure);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.UIFigure);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = '[mm]';
            app.DeviceRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.DeviceRadiusEditFieldLabel.Text = 'Device Radius';
            app.DeviceRadius = uieditfield(app.UIFigure, 'numeric');
            app.DeviceRadius.Limits = [0 Inf];
            app.DeviceRadius.Value = 5;
            app.mmLabel_3 = uilabel(app.UIFigure);
            app.mmLabel_3.Text = '[mm]';
            app.DistanceBetweenElectrodesLabel = uilabel(app.UIFigure);
            app.DistanceBetweenElectrodesLabel.Text = 'Distance Between Electrodes:';
            app.DistanceBetweenSurfaces = uieditfield(app.UIFigure, 'numeric');
            app.DistanceBetweenSurfaces.Value = 1;
            app.EntryRadiusLabel = uilabel(app.UIFigure);
            app.EntryRadiusLabel.Text = 'Entry Radius ';
            app.EntryRPosition = uieditfield(app.UIFigure, 'numeric');
            app.mmLabel_6 = uilabel(app.UIFigure);
            app.mmLabel_6.Text = '[mm]';
            app.ElectrodesSideOffsetLabel = uilabel(app.UIFigure);
            app.ElectrodesSideOffsetLabel.Text = 'Electrodes Side Offset';
            app.SideOffset = uieditfield(app.UIFigure, 'numeric');
            app.SideOffset.Value = 2;
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = '[mm]';
            app.ResolutionParameters = uilabel(app.UIFigure);
            app.ResolutionParameters.Text = 'Resolution Parameters:';
            app.OptimizeResolution = uicheckbox(app.UIFigure);
            app.OptimizeResolution.Text = 'Optimize Resolution';
            app.NumberofBoundariesLabel = uilabel(app.UIFigure);
            app.NumberofBoundariesLabel.Text = 'Number of Boundaries*';
            app.NumberofBoundaries = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBoundaries.Value = 100;
            app.NumberOfChargesLabel = uilabel(app.UIFigure);
            app.NumberOfChargesLabel.Text = 'Number Of Charges*';
            app.NumberOfCharges = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfCharges.Value = 25;
            app.Perinstance = uilabel(app.UIFigure);
            app.Perinstance.Text = '*Per instance';
            app.SimulateElectrons = uicheckbox(app.UIFigure);
            app.SimulateElectrons.Text = 'Simulate Electrons';
            app.SimulateElectrons.Value = true;
            app.ParticleChargeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ParticleChargeEditFieldLabel_2.Text = 'Particle Charge';
            app.ParticleCharge = uieditfield(app.UIFigure, 'numeric');
            app.ParticleCharge.Value = 1;
            app.ParticleMassEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ParticleMassEditFieldLabel_2.Text = 'Particle Mass';
            app.ParticleMass = uieditfield(app.UIFigure, 'numeric');
            app.ParticleMass.Value = 1;
            app.UseAngle = uicheckbox(app.UIFigure);
            app.UseAngle.Text = 'Use Angle';
            app.AxialEntrySpeedEditFieldLabel = uilabel(app.UIFigure);
            app.AxialEntrySpeedEditFieldLabel.Text = 'Axial Entry Speed';
            app.AxialEntrySpeed = uieditfield(app.UIFigure, 'numeric');
            app.AxialEntrySpeed.Value = 0.1;
            app.RadialEntrySpeedEditFieldLabel = uilabel(app.UIFigure);
            app.RadialEntrySpeedEditFieldLabel.Text = 'Radial Entry Speed';
            app.RadialEntrySpeed = uieditfield(app.UIFigure, 'numeric');
            app.RadialEntrySpeed.Value = 0.001;
            app.LIghtsspeedCoefficient = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficient.Text = 'LIghts'' speed Coefficient';
            app.LIghtsspeedCoefficientLabel_3 = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel_3.Text = 'LIghts'' speed Coefficient';
            app.UseModifiedBesselBasedSolution = uicheckbox(app.UIFigure);
            app.UseModifiedBesselBasedSolution.Text = 'Use Modified Bessel Based Solution';
            app.ParticleTrajectoryParameters = uilabel(app.UIFigure);
            app.ParticleTrajectoryParameters.Text = 'Particle Trajectory Parameters:';
            app.SimulationNameEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationNameEditFieldLabel.Text = 'Simulation Name:';
            app.SimulationName = uieditfield(app.UIFigure, 'text');
            app.SimulationName.Value = 'ElectroStaticLens';
            app.ElectrodesWidthEditFieldLabel = uilabel(app.UIFigure);
            app.ElectrodesWidthEditFieldLabel.Text = 'Electrodes Width';
            app.ElectrodesWidth = uieditfield(app.UIFigure, 'numeric');
            app.ElectrodesWidth.Value = 10;
            app.umLabel = uilabel(app.UIFigure);
            app.umLabel.Text = '[um]';
            app.NumberOfUnitCellsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfUnitCellsEditFieldLabel.Text = 'Number Of Unit Cells';
            app.NumberOfUnitCells = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfUnitCells.Limits = [1 Inf];
            app.NumberOfUnitCells.Value = 1;
            app.RadialDirectionGridPointsEditFieldLabel = uilabel(app.UIFigure);
            app.RadialDirectionGridPointsEditFieldLabel.Text = 'Radial Direction Grid Points';
            app.RadialDirectionGridPoints = uieditfield(app.UIFigure, 'numeric');
            app.RadialDirectionGridPoints.Value = 200;
            app.AxialDirectionGridPointsEditFieldLabel = uilabel(app.UIFigure);
            app.AxialDirectionGridPointsEditFieldLabel.Text = 'Axial Direction Grid Points';
            app.AxialDirectionGridPoints = uieditfield(app.UIFigure, 'numeric');
            app.AxialDirectionGridPoints.Value = 200;
            app.ConvergenceThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ConvergenceThresholdEditFieldLabel.Text = 'Convergence Threshold';
            app.ConvergenceThreshold = uieditfield(app.UIFigure, 'numeric');
            app.ConvergenceThreshold.Value = 100;
            app.GrowthThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.GrowthThresholdEditFieldLabel.Text = 'Growth Threshold';
            app.GrowthThreshold = uieditfield(app.UIFigure, 'numeric');
            app.GrowthThreshold.Value = 500;
            app.SimulateRelativeTrajectoryCheckBox = uicheckbox(app.UIFigure);
            app.SimulateRelativeTrajectoryCheckBox.Text = 'Simulate Relative Trajectory';
            app.SimulateRelativeTrajectoryCheckBox.Value = true;
            app.NumberofParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofParticlesEditField.Value = 50;
            app.SimulatePhaseSpaceCheckBox = uicheckbox(app.UIFigure);
            app.SimulatePhaseSpaceCheckBox.Text = 'SimulatePhaseSpace';
            app.SimulatePhaseSpaceCheckBox.Value = true;
            app.HighAxialEntryVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.HighAxialEntryVelocityEditFieldLabel.Text = 'High Axial Entry Velocity';
            app.HighAxialEntryVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighAxialEntryVelocityEditField.Value = 0.5;
            app.LowAxialEntryVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.LowAxialEntryVelocityEditFieldLabel.Text = 'Low Axial Entry Velocity';
            app.LowAxialEntryVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowAxialEntryVelocityEditField.Value = 0.05;
            app.HighRadialEntryVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.HighRadialEntryVelocityEditFieldLabel.Text = 'High Radial Entry Velocity';
            app.HighRadialEntryVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighRadialEntryVelocityEditField.Value = 0.05;
            app.LowRadialEntryVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.LowRadialEntryVelocityEditFieldLabel.Text = 'Low Radial Entry Velocity';
            app.LowRadialEntryVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowRadialEntryVelocityEditField.Value = 0.001;
            app.ExitRadiusThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ExitRadiusThresholdEditFieldLabel.Text = 'Exit Radius Threshold';
            app.ExitRadiusThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExitRadiusThresholdEditField.Value = 0.5;
            app.ElectronElectrodeminimumdistanceEditFieldLabel = uilabel(app.UIFigure);
            app.ElectronElectrodeminimumdistanceEditFieldLabel.Text = 'Electron-Electrode minimum distance';
            app.ElectronElectrodeminimumdistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElectronElectrodeminimumdistanceEditField.Value = 10;
            app.umLabel_2 = uilabel(app.UIFigure);
            app.umLabel_2.Text = '[um]';
            app.LIghtsspeedCoefficientLabel_4 = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel_4.Text = 'LIghts'' speed Coefficient';
            app.LIghtsspeedCoefficientLabel_5 = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel_5.Text = 'LIghts'' speed Coefficient';
            app.LIghtsspeedCoefficientLabel_6 = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel_6.Text = 'LIghts'' speed Coefficient';
            app.LIghtsspeedCoefficientLabel_7 = uilabel(app.UIFigure);
            app.LIghtsspeedCoefficientLabel_7.Text = 'LIghts'' speed Coefficient';
            app.mmLabel_7 = uilabel(app.UIFigure);
            app.mmLabel_7.Text = '[mm]';
            app.EraseOldSim = uicheckbox(app.UIFigure);
            app.EraseOldSim.Text = {'Overwrite old simulation contents'; '(Useful if a previous simulation with the same name already exists on the disk'; 'NOTE: If this box is unchecked and a simulation with the same name already'; 'exists, the device parameters that are set will be ignored)'};
            app.EraseOldSim.Value = true;
            app.newRandSeed = uicheckbox(app.UIFigure);
            app.newRandSeed.Text = 'Generate new random seed (for particle trajectory simulation)';
            app.recordPhaseSpaceVideo = uicheckbox(app.UIFigure);
            app.recordPhaseSpaceVideo.Text = 'Record phase space video';
            app.EntryBeamRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.EntryBeamRadiusEditFieldLabel.Text = 'Entry Beam Radius';
            app.EntryBeamRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntryBeamRadiusEditField.Value = 0.5;
            app.mmLabel_8 = uilabel(app.UIFigure);
            app.mmLabel_8.Text = '[mm]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EinzelLensGui_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end