classdef figExtractor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ElectrostaticLensPostProcessingToolLabel  matlab.ui.control.Label
        SimulationdatabasepathEditFieldLabel  matlab.ui.control.Label
        SimulationdatabasepathEditField  matlab.ui.control.EditField
        DeviceParametersButtonGroup   matlab.ui.container.ButtonGroup
        DistanceBetweenElectrodesButton  matlab.ui.control.RadioButton
        VolatgeButton                 matlab.ui.control.RadioButton
        NumberOfCellsButton           matlab.ui.control.RadioButton
        ApertureRadiusButton          matlab.ui.control.RadioButton
        distanceBetweenElectrodesEditField  matlab.ui.control.NumericEditField
        VoltageEditField              matlab.ui.control.NumericEditField
        NumberOfCellsEditField        matlab.ui.control.NumericEditField
        apertureRadiusEditField       matlab.ui.control.NumericEditField
        mmLabel                       matlab.ui.control.Label
        KvLabel                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        ExtractFigsButton             matlab.ui.control.Button
        FiguresToExtractPanel         matlab.ui.container.Panel
        ChargeDistributionCheckBox    matlab.ui.control.CheckBox
        ProblematicParticlesCheckBox  matlab.ui.control.CheckBox
        PhaseSpaceFinalSimCheckBox    matlab.ui.control.CheckBox
        PhaseSpaceVideoCheckBox       matlab.ui.control.CheckBox
        FullSimCheckBox               matlab.ui.control.CheckBox
        FocusedNotFocusedCheckBox     matlab.ui.control.CheckBox
        NewParametersPanel            matlab.ui.container.Panel
        FocusRadiusCheckBox           matlab.ui.control.CheckBox
        focuseRadiusEditField         matlab.ui.control.NumericEditField
        mmLabel_3                     matlab.ui.control.Label
        ParticlesSpeedToShowPanel     matlab.ui.container.Panel
        BetaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.NumericEditField
        cLabel                        matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: ExtractFigsButton
        function ExtractFigsButtonPushed(app, event)
            params.distanceBetweenElectrodesSim = app.DistanceBetweenElectrodesButton.Value;
            params.repetitionsSim               = app.NumberOfCellsButton.Value;
            params.voltageSim                   = app.VolatgeButton.Value;
            params.electrodeRadiusSim           = app.ApertureRadiusButton.Value;
            params.distanceBetweenElectrodesVal = app.distanceBetweenElectrodesEditField.Value;
            params.repetitionsVal               = app.NumberOfCellsEditField.Value;
            params.voltageVal                   = app.VoltageEditField.Value;
            params.electrodeRadiusVal           = app.apertureRadiusEditField.Value;
            params.beta                         = app.BetaEditField.Value;      
            params.simulateChargeDist           = app.ChargeDistributionCheckBox.Value;
            params.simulateProbPart             = app.ProblematicParticlesCheckBox.Value;
            params.simulatePhaseSpace           = app.PhaseSpaceFinalSimCheckBox.Value;
            params.simulatePhaseSpaceVideo      = app.PhaseSpaceVideoCheckBox.Value;
            params.simulateFullSim              = app.FullSimCheckBox.Value;
            params.useNewFocusRadius            = app.FocusRadiusCheckBox.Value;
            params.newFocusRadius               = app.focuseRadiusEditField.Value;
            params.simPath                      = app.SimulationdatabasepathEditField.Value;
            params.plotNotFocused               = app.FocusedNotFocusedCheckBox.Value;
            
            addpath(genpath([pwd]));
            
            runPostProcessing(params)
                                   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ElectrostaticLensPostProcessingToolLabel = uilabel(app.UIFigure);
            app.ElectrostaticLensPostProcessingToolLabel.Text = 'Electrostatic Lens Post Processing Tool';
            app.SimulationdatabasepathEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationdatabasepathEditFieldLabel.Text = 'Simulation database path';
            app.SimulationdatabasepathEditField = uieditfield(app.UIFigure, 'text');
            app.SimulationdatabasepathEditField.Value = '..\simulations\ElectrostaticLens';
            app.DeviceParametersButtonGroup = uibuttongroup(app.UIFigure);
            app.DeviceParametersButtonGroup.Title = 'Device Parameters';
            app.DistanceBetweenElectrodesButton = uiradiobutton(app.DeviceParametersButtonGroup);
            app.DistanceBetweenElectrodesButton.Text = 'Distance Between Electrodes';
            app.DistanceBetweenElectrodesButton.Value = true;
            app.VolatgeButton = uiradiobutton(app.DeviceParametersButtonGroup);
            app.VolatgeButton.Text = 'Volatge';
            app.NumberOfCellsButton = uiradiobutton(app.DeviceParametersButtonGroup);
            app.NumberOfCellsButton.Text = 'Number Of Cells';
            app.ApertureRadiusButton = uiradiobutton(app.DeviceParametersButtonGroup);
            app.ApertureRadiusButton.Text = 'Aperture Radius';
            app.distanceBetweenElectrodesEditField = uieditfield(app.DeviceParametersButtonGroup, 'numeric');
            app.VoltageEditField = uieditfield(app.DeviceParametersButtonGroup, 'numeric');
            app.NumberOfCellsEditField = uieditfield(app.DeviceParametersButtonGroup, 'numeric');
            app.apertureRadiusEditField = uieditfield(app.DeviceParametersButtonGroup, 'numeric');
            app.mmLabel = uilabel(app.DeviceParametersButtonGroup);
            app.mmLabel.Text = '[mm]';
            app.KvLabel = uilabel(app.DeviceParametersButtonGroup);
            app.KvLabel.Text = '[Kv]';
            app.Label = uilabel(app.DeviceParametersButtonGroup);
            app.Label.Text = '[#]';
            app.mmLabel_2 = uilabel(app.DeviceParametersButtonGroup);
            app.mmLabel_2.Text = '[mm]';
            app.ExtractFigsButton = uibutton(app.UIFigure, 'push');
            app.ExtractFigsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractFigsButtonPushed, true);
            app.ExtractFigsButton.Text = 'Extract Figs!';
            app.FiguresToExtractPanel = uipanel(app.UIFigure);
            app.FiguresToExtractPanel.Title = 'Figures To Extract:';
            app.ChargeDistributionCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.ChargeDistributionCheckBox.Text = 'Charge Distribution';
            app.ProblematicParticlesCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.ProblematicParticlesCheckBox.Text = 'Problematic Particles';
            app.PhaseSpaceFinalSimCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.PhaseSpaceFinalSimCheckBox.Text = 'Phase Space Final Sim';
            app.PhaseSpaceVideoCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.PhaseSpaceVideoCheckBox.Text = 'Phase Space Video';
            app.FullSimCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.FullSimCheckBox.Text = 'Full Sim';
            app.FocusedNotFocusedCheckBox = uicheckbox(app.FiguresToExtractPanel);
            app.FocusedNotFocusedCheckBox.Text = 'Focused & Not Focused';
            app.NewParametersPanel = uipanel(app.UIFigure);
            app.NewParametersPanel.Title = 'New Parameters';
            app.FocusRadiusCheckBox = uicheckbox(app.NewParametersPanel);
            app.FocusRadiusCheckBox.Text = 'Focus Radius';
            app.focuseRadiusEditField = uieditfield(app.NewParametersPanel, 'numeric');
            app.mmLabel_3 = uilabel(app.NewParametersPanel);
            app.mmLabel_3.Text = '[mm]';
            app.ParticlesSpeedToShowPanel = uipanel(app.UIFigure);
            app.ParticlesSpeedToShowPanel.Title = 'Particles Speed To Show';
            app.BetaEditFieldLabel = uilabel(app.ParticlesSpeedToShowPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.ParticlesSpeedToShowPanel, 'numeric');
            app.cLabel = uilabel(app.ParticlesSpeedToShowPanel);
            app.cLabel.Text = '[c]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = figExtractor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end